classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Theta_1degLabel        matlab.ui.control.Label
        Theta_1                matlab.ui.control.NumericEditField
        Theta_2degLabel        matlab.ui.control.Label
        Theta_2                matlab.ui.control.NumericEditField
        Theta_3degLabel        matlab.ui.control.Label
        Theta_3                matlab.ui.control.NumericEditField
        Theta_4degLabel        matlab.ui.control.Label
        Theta_4                matlab.ui.control.NumericEditField
        Theta_5degLabel        matlab.ui.control.Label
        Theta_5                matlab.ui.control.NumericEditField
        Theta_6degLabel        matlab.ui.control.Label
        Theta_6                matlab.ui.control.NumericEditField
        Pos_XmLabel            matlab.ui.control.Label
        Pos_X                  matlab.ui.control.NumericEditField
        Pos_YmLabel            matlab.ui.control.Label
        Pos_Y                  matlab.ui.control.NumericEditField
        Pos_ZmLabel            matlab.ui.control.Label
        Pos_Z                  matlab.ui.control.NumericEditField
        ForwardButton          matlab.ui.control.Button
        InverseButton          matlab.ui.control.Button
        KinematicsButtonGroup  matlab.ui.container.ButtonGroup
        Forward                matlab.ui.control.RadioButton
        Inverse                matlab.ui.control.RadioButton
        AnthropomorphicarmwithsphericalwristLabel  matlab.ui.control.Label
        SimulateButton         matlab.ui.control.Button
        DemoButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pos_X.Editable = 'off';
            app.Pos_Y.Editable = 'off';
            app.Pos_Z.Editable = 'off';
            app.InverseButton.Enable = 'off';
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
%             Th_1 = app.Theta_1.Value*pi/180;
%             Th_2 = app.Theta_2.Value*pi/180;
%             Th_3 = app.Theta_3.Value*pi/180;
%             Th_4 = app.Theta_4.Value*pi/180;
%             Th_5 = app.Theta_5.Value*pi/180;
%             Th_6 = app.Theta_6.Value*pi/180;

            Th_1 = app.Theta_1.Value;
            Th_2 = app.Theta_2.Value;
            Th_3 = app.Theta_3.Value;
            Th_4 = app.Theta_4.Value;
            Th_5 = app.Theta_5.Value;
            Th_6 = app.Theta_6.Value;
           
            L(1) = Link('revolute','d',1,'a',0,'alpha',pi/2);
            L(2) = Link('revolute','d',0,'a',1,'alpha',0);
            L(3) = Link('revolute','d',0.25,'a',0,'alpha',pi/2);
            L(4) = Link('revolute','d',1,'a',0,'alpha',-pi/2);
            L(5) = Link('revolute','d',0,'a',0,'alpha',pi/2);
            L(6) = Link('revolute','d',0,'a',0,'alpha',0);
            
            Robot = SerialLink(L);
            Robot.name = 'Anthro-Arm + Wrist-Joint';
            Robot.plot([Th_1 Th_2 Th_3 Th_4 Th_5 Th_6]);
            
%             T = Robot.fkine([Th_1 Th_2 Th_3 Th_4 Th_5 Th_6]);
%             app.Pos_X.Value = T.t(1);
%             app.Pos_Y.Value = T.t(2);
%             app.Pos_Z.Value = T.t(3);

            T = forwardK(Th_1, Th_2, Th_3, Th_4, Th_5, Th_6);
            app.Pos_X.Value = T(1);
            app.Pos_Y.Value = T(2);
            app.Pos_Z.Value = T(3);
        end

        % Selection changed function: KinematicsButtonGroup
        function KinematicsButtonGroupSelectionChanged(app, event)
            selectedButton = app.KinematicsButtonGroup.SelectedObject;
            if(selectedButton == app.Forward)
                app.Pos_X.Editable = 'off';
                app.Pos_Y.Editable = 'off';
                app.Pos_Z.Editable = 'off';
                app.InverseButton.Enable = 'off';
                
                app.Theta_1.Editable = 'on';
                app.Theta_2.Editable = 'on';
                app.Theta_3.Editable = 'on';
                app.Theta_4.Editable = 'on';
                app.Theta_5.Editable = 'on';
                app.Theta_6.Editable = 'on';
                app.ForwardButton.Enable = 'on';
            else
                app.Pos_X.Editable = 'on';
                app.Pos_Y.Editable = 'on';
                app.Pos_Z.Editable = 'on';
                app.InverseButton.Enable = 'on';
                
                app.Theta_1.Editable = 'off';
                app.Theta_2.Editable = 'off';
                app.Theta_3.Editable = 'off';
                app.Theta_4.Editable = 'off';
                app.Theta_5.Editable = 'off';
                app.Theta_6.Editable = 'off';
                app.ForwardButton.Enable = 'off';
            end
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            PX = app.Pos_X.Value;
            PY = app.Pos_Y.Value;
            PZ = app.Pos_Z.Value;
            
            L(1) = Link('revolute','d',1,'a',0,'alpha',pi/2);
            L(2) = Link('revolute','d',0,'a',1,'alpha',0);
            L(3) = Link('revolute','d',0.25,'a',0,'alpha',pi/2);
            L(4) = Link('revolute','d',1,'a',0,'alpha',-pi/2);
            L(5) = Link('revolute','d',0,'a',0,'alpha',pi/2);
            L(6) = Link('revolute','d',0,'a',0,'alpha',0);
            
            Robot = SerialLink(L);
            Robot.name = 'Anthro-Arm + Wrist-Joint';
            
            T = [ 1 0 0 PX;
                  0 1 0 PY;
                  0 0 1 PZ;
                  0 0 0 1];
            
            try
%                 J = Robot.ikine(T,'mask',[1 1 1 0 0 0])*180/pi;
                J = inverseK(PX,PY,PZ);
                Robot.plot(J);
                
                app.Theta_1.Value = J(1);
                app.Theta_2.Value = J(2);
                app.Theta_3.Value = J(3);
                app.Theta_4.Value = J(4);
                app.Theta_5.Value = J(5);
                app.Theta_6.Value = J(6);
            catch
                warndlg("Invalid inputs!");
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            L(1) = Link('revolute','d',1,'a',0,'alpha',pi/2);
            L(2) = Link('revolute','d',0,'a',1,'alpha',0);
            L(3) = Link('revolute','d',0.25,'a',0,'alpha',pi/2);
            L(4) = Link('revolute','d',1,'a',0,'alpha',-pi/2);
            L(5) = Link('revolute','d',0,'a',0,'alpha',pi/2);
            L(6) = Link('revolute','d',0,'a',0,'alpha',0);
            
            Robot = SerialLink(L);
            Robot.name = 'Anthro-Arm + Wrist-Joint';
            Robot.teach;
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)
            L(1) = Link('revolute','d',1,'a',0,'alpha',pi/2);
            L(2) = Link('revolute','d',0,'a',1,'alpha',0);
            L(3) = Link('revolute','d',0.25,'a',0,'alpha',pi/2);
            L(4) = Link('revolute','d',1,'a',0,'alpha',-pi/2);
            L(5) = Link('revolute','d',0,'a',0,'alpha',pi/2);
            L(6) = Link('revolute','d',0,'a',0,'alpha',0);
            
            Robot = SerialLink(L);
            Robot.name = 'Anthro-Arm + Wrist-Joint';
            
            for th=-deg2rad(170):0.05:0
                Robot.plot([th 0 0 0 0 0])
                pause(0.01)
            end
            for th=0:0.05:deg2rad(75)
                Robot.plot([0 th 0 0 0 0])
                pause(0.1)
            end
            for th=0:0.05:deg2rad(60)
                Robot.plot([0 deg2rad(75) th 0 0 0])
                pause(0.1)
            end
            for th=0:0.05:deg2rad(75)
                Robot.plot([0 deg2rad(75) deg2rad(60) th 0 0])
                pause(0.1)
            end
            for th=0:0.05:pi/3
                Robot.plot([0 deg2rad(75) deg2rad(60) deg2rad(75) th 0])
                pause(0.1)
            end
            for th=0:0.05:pi/3
                Robot.plot([0 deg2rad(75) deg2rad(60) deg2rad(75) pi/3 th])
                pause(0.1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Theta_1degLabel = uilabel(app.UIFigure);
            app.Theta_1degLabel.Text = 'Theta_1 (deg)';
            app.Theta_1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_2degLabel = uilabel(app.UIFigure);
            app.Theta_2degLabel.Text = 'Theta_2 (deg)';
            app.Theta_2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_3degLabel = uilabel(app.UIFigure);
            app.Theta_3degLabel.Text = 'Theta_3 (deg)';
            app.Theta_3 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_4degLabel = uilabel(app.UIFigure);
            app.Theta_4degLabel.Text = 'Theta_4 (deg)';
            app.Theta_4 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_5degLabel = uilabel(app.UIFigure);
            app.Theta_5degLabel.Text = 'Theta_5 (deg)';
            app.Theta_5 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_6degLabel = uilabel(app.UIFigure);
            app.Theta_6degLabel.Text = 'Theta_6 (deg)';
            app.Theta_6 = uieditfield(app.UIFigure, 'numeric');
            app.Pos_XmLabel = uilabel(app.UIFigure);
            app.Pos_XmLabel.Text = 'Pos_X (m)';
            app.Pos_X = uieditfield(app.UIFigure, 'numeric');
            app.Pos_YmLabel = uilabel(app.UIFigure);
            app.Pos_YmLabel.Text = 'Pos_Y (m)';
            app.Pos_Y = uieditfield(app.UIFigure, 'numeric');
            app.Pos_ZmLabel = uilabel(app.UIFigure);
            app.Pos_ZmLabel.Text = 'Pos_Z (m)';
            app.Pos_Z = uieditfield(app.UIFigure, 'numeric');
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.KinematicsButtonGroup = uibuttongroup(app.UIFigure);
            app.KinematicsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KinematicsButtonGroupSelectionChanged, true);
            app.KinematicsButtonGroup.Title = 'Kinematics';
            app.Forward = uiradiobutton(app.KinematicsButtonGroup);
            app.Forward.Text = 'Forward';
            app.Forward.Value = true;
            app.Inverse = uiradiobutton(app.KinematicsButtonGroup);
            app.Inverse.Text = 'Inverse';
            app.AnthropomorphicarmwithsphericalwristLabel = uilabel(app.UIFigure);
            app.AnthropomorphicarmwithsphericalwristLabel.Text = 'Anthropomorphic arm with spherical wrist';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.DemoButton = uibutton(app.UIFigure, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end